classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OryginalnyobrazLabel            matlab.ui.control.Label
        ObrazposegmentacjibarwnejLabel  matlab.ui.control.Label
        WczytajobrazButton              matlab.ui.control.Button
        ZapiszobrazButton               matlab.ui.control.Button
        ParametrSliderLabel             matlab.ui.control.Label
        ParametrSlider                  matlab.ui.control.Slider
        SegmentacjaButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajobrazButton
        function LoadImage(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            ImageFile = imread(fullname);
            imshow(ImageFile, 'Parent', app.UIAxes);
        end

        % Button pushed function: ZapiszobrazButton
        function SaveImage(app, event)
            % Create new axis
            newAxes = axes;
            
            % Copy all objects from UIAxes to new axis
            newAxes.Title= app.UIAxes2.Title;
            newAxes.XLabel = app.UIAxes2.XLabel;
            newAxes.YLabel = app.UIAxes2.YLabel;
            copyobj(app.UIAxes2.Children, newAxes)
            
            fig2Save = ancestor(newAxes, 'figure');
            set(gca, 'Visible', 'off');
       
            %dialog box,save as
            filter = {'*.jpg';'*.png'};
            [filename, pathname] = uiputfile(filter);
            newfilename = fullfile(pathname, filename);
            saveas(fig2Save, newfilename);
        end

        % Value changed function: ParametrSlider
        function ChangeParameter(app, event)
            %value = app.ParametrSlider.Value;
            
        end

        % Button pushed function: SegmentacjaButton
        function Segmentation(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OryginalnyobrazLabel = uilabel(app.UIFigure);
            app.OryginalnyobrazLabel.Text = 'Oryginalny obraz';
            app.ObrazposegmentacjibarwnejLabel = uilabel(app.UIFigure);
            app.ObrazposegmentacjibarwnejLabel.Text = 'Obraz po segmentacji barwnej';
            app.WczytajobrazButton = uibutton(app.UIFigure, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImage, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.ZapiszobrazButton = uibutton(app.UIFigure, 'push');
            app.ZapiszobrazButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImage, true);
            app.ZapiszobrazButton.Text = 'Zapisz obraz';
            app.ParametrSliderLabel = uilabel(app.UIFigure);
            app.ParametrSliderLabel.Text = 'Parametr';
            app.ParametrSlider = uislider(app.UIFigure);
            app.ParametrSlider.ValueChangedFcn = createCallbackFcn(app, @ChangeParameter, true);
            app.SegmentacjaButton = uibutton(app.UIFigure, 'push');
            app.SegmentacjaButton.ButtonPushedFcn = createCallbackFcn(app, @Segmentation, true);
            app.SegmentacjaButton.Text = 'Segmentacja';
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end